classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PokmonAnalyzerUIFigure     matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SummaryTab                 matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        Label2_1                   matlab.ui.control.Label
        Label2_3                   matlab.ui.control.Label
        Label2_6                   matlab.ui.control.Label
        OwnSpriteLabel             matlab.ui.control.Label
        AttacksLabel               matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        TextArea_13                matlab.ui.control.TextArea
        TextArea_14                matlab.ui.control.TextArea
        TextArea_15                matlab.ui.control.TextArea
        TextArea_16                matlab.ui.control.TextArea
        GridLayout4                matlab.ui.container.GridLayout
        TextArea_9                 matlab.ui.control.TextArea
        TextArea_10                matlab.ui.control.TextArea
        GridLayout5                matlab.ui.container.GridLayout
        TextArea_11                matlab.ui.control.TextArea
        TextArea_12                matlab.ui.control.TextArea
        AttacksTab                 matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        Label1                     matlab.ui.control.Label
        Label2                     matlab.ui.control.Label
        Label5                     matlab.ui.control.Label
        Label6                     matlab.ui.control.Label
        TextArea_1                 matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        TextArea_3                 matlab.ui.control.TextArea
        TextArea_4                 matlab.ui.control.TextArea
        TextArea_5                 matlab.ui.control.TextArea
        TextArea_6                 matlab.ui.control.TextArea
        TextArea_7                 matlab.ui.control.TextArea
        TextArea_8                 matlab.ui.control.TextArea
        PokmonsTab                 matlab.ui.container.Tab
        GridLayout6                matlab.ui.container.GridLayout
        Label_11                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        Label_15                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_27                   matlab.ui.control.Label
        Label_28                   matlab.ui.control.Label
        TextArea_17                matlab.ui.control.TextArea
        TextArea_18                matlab.ui.control.TextArea
        TextArea_19                matlab.ui.control.TextArea
        GridLayout8                matlab.ui.container.GridLayout
        Label_21                   matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        Label_24                   matlab.ui.control.Label
        Label_29                   matlab.ui.control.Label
        Label_30                   matlab.ui.control.Label
        TextArea_20                matlab.ui.control.TextArea
        TextArea_21                matlab.ui.control.TextArea
        TextArea_22                matlab.ui.control.TextArea
        TestingTab                 matlab.ui.container.Tab
        TestDataFolderLabel        matlab.ui.control.Label
        ChangeFolderPathButton     matlab.ui.control.Button
        RunFullTestButton          matlab.ui.control.Button
        TestingResultsLabel        matlab.ui.control.Label
        TestingResultsTextArea     matlab.ui.control.TextArea
        datasetLabel               matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        ResultsLabel               matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ChooseBattleScreenButton   matlab.ui.control.Button
        AnalyzeBattleScreenButton  matlab.ui.control.Button
        BattlefieldLabel           matlab.ui.control.Label
        GenerateRandomButton       matlab.ui.control.Button
    end

    
methods (Access = private)
     function broken = addLineBreaks(~, txt, width)
        txt = strrep(txt, newline, "");
        txt = strrep(txt, "\n", "");
        txt = convertStringsToChars(txt);
        cnt = width;    
        lngth = strlength(txt);
        while cnt < lngth
            lCnt = 0;
            if txt(cnt + 1) == " "
               txt(cnt + 1) = newline;
               cnt = cnt + width + 1;
               continue;
            end
            while txt(cnt) ~= " "
                if lCnt >= width - 1
                    cnt = cnt + width;
                    break;
                end
                cnt = cnt - 1;
                lCnt = lCnt + 1;
            end
            if txt(cnt) == " "
                txt(cnt) = newline;
                cnt = cnt + width;
            else
                firstPart = extractBetween(txt, 1, cnt - 1);
                secondPart = extractBetween(txt, cnt, strlength(txt));
                txt = sprintf("%s\n%s", convertCharsToStrings(firstPart), ...
                    convertCharsToStrings(secondPart));
                txt = convertStringsToChars(txt);
                cnt = cnt + width;
            end
        end
        broken = convertCharsToStrings(txt);
    end

    function attrListBr = getAttrListWithLB(~, attrs)
        attrs = strsplit(strtrim(attrs), " ");
        out = "";
        for i = 1: length(attrs)
            if i < length(attrs)
                out = sprintf("%s%s\n\t\t\t\t", out, attrs(i));
            else
                out = sprintf("%s%s", out, attrs(i));
            end
        end
        attrListBr = out;
    end

    function outInfo = getStatusLine(~, info, perc)
        changeVal = extractBetween(info, 2, 2);
        if ~contains(info, "-") 
            changeDir = "Increases ";
        else 
            changeDir = "Decreases ";
        end
        if ~contains(info, "U")
            who = "foe ";
        else
            who = "own ";
        end
        if contains(info, "ATT")
            what = "Attack Power ";   
        elseif contains(info, "DEF")
            what = "Defense Power ";
        elseif contains(info, "SPA")
            what = "Special Attack Power ";
        elseif contains(info, "SPD")
            what = "Special Defense Power ";
        elseif contains(info, "SPE")
            what = "Speed ";
        elseif contains(info, "CRT")
            what = "Critical Hit Rate ";
        elseif contains(info, "ACC")
            what = "Accuracy ";
        elseif contains(info, "EVA")
            what = "Evasion ";
        else
            what = "Unknown Status ";
        end
        outInfo = changeDir + who + what + "by " + changeVal + ...
            " with probability " + perc + "%";
    end

    function outInfo = getCondLine(~, info, perc)
        if contains(info, "SLEEP")
            outInfo = "Puts foe to sleep";    
        elseif contains(info, "POISEN") || contains(info, "POISON")
            outInfo = "Poisons foe";
        elseif contains(info, "FROZEN")
            outInfo = "Freezes foe";
        elseif contains(info, "BURNED")
            outInfo = "Burns foe";
        elseif contains(info, "PARALYZED")
            outInfo = "Paralyzes foe";
        elseif contains(info, "FLINCH")
            outInfo = "Makes foe flinch";
        elseif contains(info, "CONFUSION")
            outInfo = "Confuses foe";
        else
            outInfo = "Unknown effect on foe condition";
        end
        outInfo = outInfo + " with prob. of " + num2str(perc) + "%";
    end

    function phr = unCaps(~, caps)
        first = 1;
        caps = convertStringsToChars(caps);
        for i = 1:length(caps)
            if first
                caps(i) = upper(caps(i));
            else
                caps(i) = lower(caps(i)); 
            end
            if caps(i) == ' ' || caps(i) == '-'
                first = 1;
            else
                first = 0;
            end
        end
        phr = convertCharsToStrings(caps);
    end

    function fullStatus = getFullStatus(~, aStatus)
        switch(aStatus)
            case "SLP"
                fullStatus = "Sleeping";
                return;
            case "BRN"
                fullStatus = "Burned";
                return;
            case "FRZ"
                fullStatus = "Frozen";
                return;
            case "PAR"
                fullStatus = "Paralyzed";
                return;
            case "PSN"
                fullStatus = "Poisoned";
                return;
        end
        fullStatus = "-";
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       
            global frontSprites;
            global backSprites;
            global db;
            
            addpath classes\
            
            % parallelization
            clust = parcluster('local');
            parpool(min(clust.NumWorkers - 1, 4)); % number of threads, 4 are needed for best result
            
            %Create database connection
            db = Database();
            
            %load sprites into memory
            if exist('frontSprites', 'var') ~= 1 || exist('backSprites', 'var') ~= 1
                tic;
                
                [frontSprites, backSprites] = loadSprites();
                spritesLoadingTime = toc
            end
            
            if isempty(frontSprites) || isempty(backSprites)
                tic;
                
                [frontSprites, backSprites] = loadSprites();
                spritesLoadingTime = toc
            end
            
            app.AnalyzeBattleScreenButton.Enable = false;
            
            app.TextArea_1.Editable = false;
            app.TextArea_2.Editable = false;
            app.TextArea_3.Editable = false;
            app.TextArea_4.Editable = false;
            app.TextArea_5.Editable = false;
            app.TextArea_6.Editable = false;
            app.TextArea_7.Editable = false;
            app.TextArea_8.Editable = false;
        end

        % Button pushed function: ChooseBattleScreenButton
        function ChooseBattleScreenButtonPushed(app, event)
            global path;
            global Img;
            global image;
            global own;     % the own sprite
            global enemy;   % the enemy sprite
            global preprocessingHealthbarUser;
            global preprocessingHealthbarEnemy;
            global preprocessedStatus;
            global preprocessedLevel;
            global preprocessedAttacks;
            global preprocessingTimeNeeded;
            
            [tmpfile, tmppath] = uigetfile('*.png', 'Choose Pokémon Battleground');
            
            if ~isempty(tmpfile) && ~isempty(tmppath)
                if tmpfile == 0
                    return;
                end
                if tmppath == 0
                    return;
                end
                path = strcat(tmppath, tmpfile);
            else
                return;
            end
            
            if ~isempty(path)
                app.Image.ImageSource = path;
                app.Label1.Text = '';
                app.Label2.Text = '';
                app.TextArea_1.Value = '';
                app.TextArea_2.Value = '';
                app.Label5.Text = '';
                app.Label6.Text = '';
                app.TextArea_5.Value = '';
                app.TextArea_6.Value = '';
                app.Label2_1.Text = '';
                app.TextArea_9.Value = '';
                app.Label2_3.Text = '';
                app.TextArea_11.Value = '';
                app.TextArea_3.Value = '';
                app.TextArea_4.Value = '';
                app.TextArea_7.Value = '';
                app.TextArea_8.Value = '';
                app.TextArea_13.Value = '';
                app.TextArea_15.Value = '';
                app.TextArea_14.Value = '';
                app.TextArea_16.Value = '';
                app.TextArea_10.Value = '';
                app.TextArea_12.Value = '';
                app.Label_11.Text = '';
                app.Label_12.Text = '';
                app.TextArea_17.Value = '';
                app.TextArea_18.Value = '';
                app.TextArea_20.Value = '';
                app.TextArea_21.Value = '';
                app.TextArea_22.Value = '';
                app.TextArea_19.Value = '';
                
                app.AnalyzeBattleScreenButton.Enable = true;
                drawnow;
                
                Img = imread(path);
            
                tic;
                
                %image processing for all sub functions
                image = preprocessing_all(Img);
                
                %feature specific preprocessing and segmentation
                preprocessedStatus = preprocessing_status(image);
                preprocessedLevel = preprocessing_level(image);
                [own, enemy] = preprocessing_pokemon_sprites(image);
                preprocessedAttacks = preprocessing_attacks(image);
                preprocessingHealthbarEnemy = preprocessing_healthbar(image, 0);
                preprocessingHealthbarUser = preprocessing_healthbar(image, 1);

                preprocessingTimeNeeded = toc;
            end
            
        end

        % Button pushed function: AnalyzeBattleScreenButton
        function AnalyzeBattleScreenButtonPushed(app, event)
            global path;
            global db;
            global own;
            global enemy;
            global frontSprites;
            global backSprites;
            global preprocessingHealthbarUser;
            global preprocessingHealthbarEnemy;
            global preprocessedAttacks;
            global preprocessedLevel;
            global preprocessedStatus;
            global preprocessingTimeNeeded;
            
            % __________________________ main Part 
            
            if isempty(path)
                return;
            end
            
            if path == 0
                return;
            end
            
            if ~db.connection.IsOpen
                return;
            end
            
            tic;
            %reading information from the preprocessed images
            statusOutput = imageprocessing_status(preprocessedStatus);
            lvl = imageprocessing_level(preprocessedLevel);
            [ownPokemon, enemyPokemon] = imageprocessing_pokemon_sprites(own, enemy, frontSprites, backSprites, db);
            attackVec = imageprocessing_attacks(preprocessedAttacks);
            health_enemy = imageprocessing_healthbar(preprocessingHealthbarEnemy);
            health_user = imageprocessing_healthbar(preprocessingHealthbarUser);
            totalTimeNeeded = toc;
            
            % __________________________ Attack GUI Part 
            
            atk1 = Attack(attackVec(1));
            atk2 = Attack(attackVec(2));
            atk3 = Attack(attackVec(3));
            atk4 = Attack(attackVec(4));
            
            moveType1 = strrep(atk1.getType(), "Move Type:", "");
            moveType1 = strrep(moveType1, "Move type:", "");
            moveType1 = strrep(moveType1, "Move type", "");
            moveType1 = strrep(moveType1, "Type:", "");
            if ~isempty(moveType1)
                moveType1 = strtrim(moveType1);
            end
            text1 = strcat("PP: ", num2str(atk1.getPP()), " | ", moveType1, " | Pow: ", num2str(atk1.getPower()), " | P(hit) = ", ...
                num2str(atk1.getAccuracy())); 
            label1Txt = "Attack Type: " + moveType1 + newline + "Power: " + num2str(atk1.getPower()) + newline + "Accuracy: " + ...
                num2str(atk1.getAccuracy()) + newline + "PP: " + num2str(atk1.getPP()) + newline; 
            
            if ~isempty(atk1.getCond()) && atk1.getCond() ~= "N/A" && ~isempty(atk1.getCond_perc()) 
                % split delimiter = '|'
                splitStr = strsplit(atk1.getCond(), "|");
                % trim each and make linebreak after each
                label1Txt = label1Txt + newline + "Foe Cond. Changes:" + newline;
                for i = 1:length(splitStr)
                    label1Txt = label1Txt +  num2str(i) + ". : " + app.getCondLine(strtrim(splitStr(i)), ...
                        num2str(atk1.getCond_perc())) + newline;
                end
            end
            
            if ~isempty(atk1.getStatus()) && atk1.getStatus() ~= "N/A" && ~isempty(atk1.getStatus_perc())
                % split delimiter = '|'
                splitStr = strsplit(atk1.getStatus(), "|");
                % trim each and make linebreak after each
                label1Txt = label1Txt + newline + "Status Changes:" + newline;
                for i = 1:length(splitStr)
                    label1Txt = label1Txt + num2str(i) + ". : " + app.getStatusLine(strtrim(splitStr(i)), ...
                        num2str(atk1.getStatus_perc())) + newline;
                end
            end
            
            if ~isempty(atk1.getEffect()) && atk1.getEffect() ~= ""
                tooltip1 = atk1.getEffect();
                app.TextArea_3.Value = "Effect/Description:" + newline + strtrim(tooltip1);
            end 
            
            moveType2 = strrep(atk2.getType(), "Move Type:", "");
            moveType2 = strrep(moveType2, "Move type:", "");
            moveType2 = strrep(moveType2, "Move type", "");
            moveType2 = strrep(moveType2, "Type:", "");
            if ~isempty(moveType2)
                moveType2 = strtrim(moveType2);
            end
            text2 = strcat("PP: ", num2str(atk2.getPP()), " | ", moveType2, " | Pow: ", num2str(atk2.getPower()), " | P(hit) = ", ...
                num2str(atk2.getAccuracy())); 
            label2Txt = "Attack Type: " + moveType2 + newline + "Power: " + num2str(atk2.getPower()) + newline + "Accuracy: " + ...
                num2str(atk2.getAccuracy()) + newline + "PP: " + num2str(atk2.getPP()) + newline; 
            
            if ~isempty(atk2.getCond()) && atk2.getCond() ~= "N/A" && ~isempty(atk2.getCond_perc()) 
                % split delimiter = '|'
                splitStr = strsplit(atk2.getCond(), "|");
                % trim each and make linebreak after each
                label2Txt = label2Txt + newline + "Foe Cond. Changes:" + newline;
                for i = 1:length(splitStr)
                    label2Txt = label2Txt + num2str(i) + ". : " + app.getCondLine(strtrim(splitStr(i)), ...
                        num2str(atk2.getCond_perc())) + newline;
                end
            end
            
            if ~isempty(atk2.getStatus()) && atk2.getStatus() ~= "N/A" && ~isempty(atk2.getStatus_perc())
                % split delimiter = '|'
                splitStr = strsplit(atk2.getStatus(), "|");
                % trim each and make linebreak after each
                label2Txt = label2Txt + newline + "Status Changes:" + newline;
                for i = 1:length(splitStr)
                    label2Txt = label2Txt + num2str(i) + ". : " + app.getStatusLine(strtrim(splitStr(i)), ...
                        num2str(atk2.getStatus_perc())) + newline;
                end
            end
            
            if ~isempty(atk2.getEffect())
                tooltip2 = atk2.getEffect();
                app.TextArea_4.Value = "Effect/Description:" + newline + strtrim(tooltip2);
            end
            
            moveType3 = strrep(atk3.getType(), "Move Type:", "");
            moveType3 = strrep(moveType3, "Move type:", "");
            moveType3 = strrep(moveType3, "Move type", "");
            moveType3 = strrep(moveType3, "Type:", "");
            if ~isempty(moveType3)
                moveType3 = strtrim(moveType3);
            end
            text3 = strcat("PP: ", num2str(atk3.getPP()), " | ", moveType3, " | Pow: ", num2str(atk3.getPower()), " | P(hit) = ", ...
                num2str(atk3.getAccuracy())); 
            label3Txt = "Attack Type: " + moveType3 + newline + "Power: " + num2str(atk3.getPower()) + newline + "Accuracy: " + ...
                num2str(atk3.getAccuracy()) + newline + "PP: " + num2str(atk3.getPP()) + newline;
            
            if ~isempty(atk3.getCond()) && atk3.getCond() ~= "N/A" && ~isempty(atk3.getCond_perc()) 
                % split delimiter = '|'
                splitStr = strsplit(atk3.getCond(), "|");
                % trim each and make linebreak after each
                label3Txt = label3Txt + newline + "Foe Cond. Changes:" + newline;
                for i = 1:length(splitStr)
                    label3Txt = label3Txt + num2str(i) + ". : " + app.getCondLine(strtrim(splitStr(i)), ...
                        num2str(atk3.getCond_perc())) + newline;
                end
            end
            
            if ~isempty(atk3.getStatus()) && atk3.getStatus() ~= "N/A" && ~isempty(atk3.getStatus_perc())
                % split delimiter = '|'
                splitStr = strsplit(atk3.getStatus(), "|");
                % trim each and make linebreak after each
                label3Txt = label3Txt + newline + "Status Changes:" + newline;
                for i = 1:length(splitStr)
                    label3Txt = label3Txt + num2str(i) + ". : " + app.getStatusLine(strtrim(splitStr(i)), ...
                        num2str(atk3.getStatus_perc())) + newline;
                end
            end
            
            if ~isempty(atk3.getEffect())
                tooltip3 = atk3.getEffect();   
                app.TextArea_7.Value = "Effect/Description:" + newline + strtrim(tooltip3);
            end
            
            
            moveType4 = strrep(atk4.getType(), "Move Type:", "");
            moveType4 = strrep(moveType4, "Move type:", "");
            moveType4 = strrep(moveType4, "Move type", "");
            moveType4 = strrep(moveType4, "Type:", "");
            if ~isempty(moveType4)
                moveType4 = strtrim(moveType4);
            end
            text4 = strcat("PP: ", num2str(atk4.getPP()), " | ", moveType4, " | Pow: ", num2str(atk4.getPower()), " | P(hit) = ", ...
                num2str(atk4.getAccuracy())); 
            label4Txt = "Attack Type: " + moveType4 + newline + "Power: " + num2str(atk4.getPower()) + newline + "Accuracy: " + ...
                num2str(atk4.getAccuracy()) + newline + "PP: " + num2str(atk4.getPP()) + newline; 
           
             if ~isempty(atk4.getCond()) && atk4.getCond() ~= "N/A" && ~isempty(atk4.getCond_perc()) 
                % split delimiter = '|'
                splitStr = strsplit(atk4.getCond(), "|");
                % trim each and make linebreak after each
                label4Txt = label4Txt + newline + "Foe Cond. Changes:" + newline;
                for i = 1:length(splitStr)
                    label4Txt = label4Txt + num2str(i) + ". : " + app.getCondLine(strtrim(splitStr(i)), ...
                        num2str(atk4.getCond_perc())) + newline;
                end
            end
            
            if ~isempty(atk4.getStatus()) && atk4.getStatus() ~= "N/A" && ~isempty(atk4.getStatus_perc())
                % split delimiter = '|'
                splitStr = strsplit(atk4.getStatus(), "|");
                % trim each and make linebreak after each
                label4Txt = label4Txt + newline + "Status Changes:" + newline;
                for i = 1:length(splitStr)
                    label4Txt = label4Txt + num2str(i) + ". : " + app.getStatusLine(strtrim(splitStr(i)), ...
                        num2str(atk4.getStatus_perc())) + newline;
                end
            end
            
            if ~isempty(atk4.getEffect())
                tooltip4 = atk4.getEffect();
                app.TextArea_8.Value = "Effect/Description:" + newline + strtrim(tooltip4);
            end
            
            app.Label1.Text = sprintf("%s", attackVec(1));
            app.Label2.Text = sprintf("%s", attackVec(2));
            
            app.TextArea_1.Value = label1Txt;
            app.TextArea_2.Value = label2Txt;
            app.Label5.Text = sprintf("%s", attackVec(3));
            app.Label6.Text = sprintf("%s", attackVec(4));
            
            app.TextArea_5.Value = label3Txt;
            app.TextArea_6.Value = label4Txt;
            drawnow
            
            % __________________________ Summary GUI Part 
            
            app.Label2_1.Text = enemyPokemon.name;
            app.Label2_3.Text = ownPokemon.name;
            ownPokemonInfo = "HP: " + (round(health_user, 2) * 100) + "%" + newline;
            ownPokemonInfo = ownPokemonInfo + "Level: " + lvl(2) + newline;
            ownPokemonInfo = ownPokemonInfo + "Status: " + app.getFullStatus(statusOutput(1));
            app.TextArea_11.Value = ownPokemonInfo;
            enemyPokemonInfo = "HP: " + (round(health_enemy, 2) * 100) + "%" + newline;
            enemyPokemonInfo = enemyPokemonInfo + "Level: " + lvl(1) + newline;
            enemyPokemonInfo = enemyPokemonInfo + "Status: " + app.getFullStatus(statusOutput(2));
            app.TextArea_9.Value = enemyPokemonInfo;
            app.TextArea_10.Value = "Type 1: " + enemyPokemon.type1 + newline + "Type 2: " + enemyPokemon.type2;
            app.TextArea_12.Value = "Type 1: " + ownPokemon.type1 + newline + "Type 2: " + ownPokemon.type2;
            app.TextArea_13.Value = attackVec(1) + newline + "[Attack Type: " + moveType1 + "]";
            app.TextArea_14.Value = attackVec(2) + newline + "[Attack Type: " + moveType2 + "]";
            app.TextArea_15.Value = attackVec(3) + newline + "[Attack Type: " + moveType3 + "]";
            app.TextArea_16.Value = attackVec(4) + newline + "[Attack Type: " + moveType4 + "]";
            
            % __________________________ Pokemon Sprites GUI Part 
            
            app.Label_11.Text = enemyPokemon.name;
            app.Label_12.Text = ownPokemon.name;
            
            % enemy
            txt = "Type 1: " + enemyPokemon.type1 + newline;
            txt = txt + "Type 2: " + enemyPokemon.type2 + newline;
            txt = txt + "HP: " + num2str(enemyPokemon.hp);
            app.TextArea_17.Value = txt;
            txt = "Attack: " + num2str(enemyPokemon.attack) + newline;
            txt = txt + "Defense: " + num2str(enemyPokemon.defense) + newline;
            txt = txt + "Total: " + num2str(enemyPokemon.total);
            app.TextArea_18.Value = txt;
            txt = "Sp. Attack: " + num2str(enemyPokemon.spAttack) + newline;
            txt = txt + "Sp. Defense: " + num2str(enemyPokemon.spDefense) + newline;
            txt = txt + "Speed: " + num2str(enemyPokemon.speed);
            app.TextArea_19.Value = txt;
            
            % own
            
            txt = "Type 1: " + ownPokemon.type1 + newline;
            txt = txt + "Type 2: " + ownPokemon.type2 + newline;
            txt = txt + "HP: " + num2str(ownPokemon.hp);
            app.TextArea_20.Value = txt;
            txt = "Attack: " + num2str(ownPokemon.attack) + newline;
            txt = txt + "Defense: " + num2str(ownPokemon.defense) + newline;
            txt = txt + "Total: " + num2str(ownPokemon.total);
            app.TextArea_21.Value = txt;
            txt = "Sp. Attack: " + num2str(ownPokemon.spAttack) + newline;
            txt = txt + "Sp. Defense: " + num2str(ownPokemon.spDefense) + newline;
            txt = txt + "Speed: " + num2str(ownPokemon.speed);
            app.TextArea_22.Value = txt;
            
            % __________________________ Draw Image of Battle Screen with
            % Overlay
            
            img = imread(path);
            img = imresize(img, [1600 2400]);
            % insert text and/or shape in image
            
            img = insertText(img, [130 1175], text1, 'FontSize', 80, 'BoxOpacity', 0.6, ...
                'BoxColor', 'yellow', 'TextColor', 'black');
           
            img = insertText(img, [600 1030], text2, 'FontSize', 80, 'BoxOpacity', 0.8, ...
                'BoxColor', 'red', 'TextColor', 'white');
           
            img = insertText(img, [130 1450], text3, 'FontSize', 80, 'BoxOpacity', 0.7, ...
                'BoxColor', 'blue', 'TextColor', 'white');
            
            img = insertText(img, [600 1320], text4, 'FontSize', 80, 'BoxOpacity', 0.6, ...
                'BoxColor', 'green', 'TextColor', 'black');
            
            img = insertText(img, [999 0], "Preprocessing Duration: " + num2str(preprocessingTimeNeeded), 'FontSize', 80, ...
                'BoxOpacity', 0.6, 'BoxColor', 'black', 'TextColor', 'white');
            
            img = insertText(img, [999 150], "Processing Duration: " + num2str(totalTimeNeeded), 'FontSize', 80, ...
                'BoxOpacity', 0.6, 'BoxColor', 'black', 'TextColor', 'white');
            
            app.Image.ImageSource = img;
                       
            app.AnalyzeBattleScreenButton.Enable = false;
        end

        % Button pushed function: GenerateRandomButton
        function GenerateRandomButtonPushed(app, event)
            global Img;
            global image;
            global own;     % the own sprite
            global enemy;   % the enemy sprite
            global preprocessingHealthbarUser;
            global preprocessingHealthbarEnemy;
            global path;
            global preprocessedAttacks;
            global preprocessedStatus;
            global preprocessedLevel;
            global preprocessingTimeNeeded;
            
            % choose random battle screen from .\dataset
            
            % count img files
            a = dir('..\dataset\*.png');
            sz = size(a, 1);
            
            if sz == 0
                return;
            end
            % generate random number between 1 and num_of_files
            rng('shuffle');
            r = (sz - 1).*rand(1,1) + 1;
            r = fix(r);
            
            % choose the file
            fil = a(r);
            path = strcat('..\dataset\', fil.name);
         
            if ~isempty(path)
                app.Image.ImageSource = path;
                app.Label1.Text = '';
                app.Label2.Text = '';
                app.TextArea_1.Value = '';
                app.TextArea_2.Value = '';
                app.Label5.Text = '';
                app.Label6.Text = '';
                app.TextArea_5.Value = '';
                app.TextArea_6.Value = '';
                app.Label2_1.Text = '';
                app.TextArea_9.Value = '';
                app.Label2_3.Text = '';
                app.TextArea_11.Value = '';
                app.TextArea_3.Value = '';
                app.TextArea_4.Value = '';
                app.TextArea_7.Value = '';
                app.TextArea_8.Value = '';
                app.TextArea_13.Value = '';
                app.TextArea_15.Value = '';
                app.TextArea_14.Value = '';
                app.TextArea_16.Value = '';
                app.TextArea_10.Value = '';
                app.TextArea_12.Value = '';
                app.Label_11.Text = '';
                app.Label_12.Text = '';
                app.TextArea_17.Value = '';
                app.TextArea_18.Value = '';
                app.TextArea_20.Value = '';
                app.TextArea_21.Value = '';
                app.TextArea_22.Value = '';
                app.TextArea_19.Value = '';
                
                app.AnalyzeBattleScreenButton.Enable = true;
                drawnow;
                
                Img = imread(path); 
                
                tic;
                
                %image processing for all sub functions
                image = preprocessing_all(Img);
                
                %feature specific preprocessing and segmentation
                preprocessedStatus = preprocessing_status(image);
                preprocessedLevel = preprocessing_level(image);
                [own, enemy] = preprocessing_pokemon_sprites(image);
                preprocessedAttacks = preprocessing_attacks(image);
                preprocessingHealthbarEnemy = preprocessing_healthbar(image, 0);
                preprocessingHealthbarUser = preprocessing_healthbar(image, 1);
                
                preprocessingTimeNeeded = toc;
            end
        end

        % Close request function: PokmonAnalyzerUIFigure
        function PokmonAnalyzerUIFigureCloseRequest(app, event)
            global db;
            
            delete(gcp('nocreate'));
            db.close();
            delete(app);
            
        end

        % Button pushed function: RunFullTestButton
        function RunFullTestButtonPushed(app, event)
            
            global db;
            global frontSprites;
            global backSprites;
            
            app.TestingResultsTextArea.Value = "";
            
            if isempty(app.datasetLabel) || app.datasetLabel.Text == ""
                return;
            end
            app.RunFullTestButton.Enable = false;
            app.ChangeFolderPathButton.Enable = false;
            
            path = app.datasetLabel.Text;
            
            a = dir(strcat(path, '\*.png'));
            sz = size(a, 1);
            
            if sz == 0
                return;
            end
            
            txt = "";
            
            tableName = "TestData";
            
            app.TestingResultsTextArea.Value = "Progress: 0.0000%";
            
            nrFound = 0;
            nrSpriteOk = 0;
            nrEnemySpriteOk = 0;
            nrHpOk = 0;
            nrEnemyHpOk = 0;
            nrStatOk = 0;
            nrEnemyStatOk = 0;
            nrLvlOk = 0;
            nrEnemyLvlOk = 0;
            nrAtk1Ok = 0;
            nrAtk2Ok = 0;
            nrAtk3Ok = 0;
            nrAtk4Ok = 0;
            
            for i = 1:sz
                app.TestingResultsTextArea.Value = app.TestingResultsTextArea.Value + " @: " + a(i).name;
                app.Image2.ImageSource = a(i).folder + "\" + a(i).name;
                % Files where mequal causes error - skip these files for
                % now 
                % ToDo: Fix Bug!!!
                %if a(i).name == "PN49.png" || a(i).name == "ma16.png" || a(i).name == "re27.png" || a(i).name == "re28.png" || a(i).name == "re31.png" || a(i).name == "re49.png" || a(i).name == "re51.png" || a(i).name == "re52.png" || a(i).name == "re53.png" || a(i).name == "re69.png" || a(i).name == "re78.png" || a(i).name == "re79.png" || a(i).name == "re80.png"
                %    app.TestingResultsTextArea.Value = "Progress: " + num2str(double(i) / sz * 100.0) + "%";
                %    continue;
                %end
                drawnow;
                tic;
                Img = imread(path + "\" + a(i).name); 
                
                %image processing for all sub functions
                image = preprocessing_all(Img);
                
                %feature specific preprocessing and segmentation
                preprocessedStatus = preprocessing_status(image);
                preprocessedLevel = preprocessing_level(image);
                [own, enemy] = preprocessing_pokemon_sprites(image);
                preprocessedAttacks = preprocessing_attacks(image);
                preprocessingHealthbarEnemy = preprocessing_healthbar(image, 0);
                preprocessingHealthbarUser = preprocessing_healthbar(image, 1);
             
                if isempty(path)
                    return;
                end
            
                if path == 0
                    return;
                end
            
                if ~db.connection.IsOpen
                    txt = "Database connection failed..";
                    app.TestingResultsTextArea.Value = txt;
                    return;
                end
            
                %reading information from the preprocessed images
                statusOutput = imageprocessing_status(preprocessedStatus);
                lvl = imageprocessing_level(preprocessedLevel);
                [ownPokemon, enemyPokemon] = imageprocessing_pokemon_sprites(own, enemy, frontSprites, backSprites, db);
                attackVec = imageprocessing_attacks(preprocessedAttacks);
                health_enemy = imageprocessing_healthbar(preprocessingHealthbarEnemy);
                health_user = imageprocessing_healthbar(preprocessingHealthbarUser);
                
                totalTime = toc;
                
                Attack(attackVec(1));
                Attack(attackVec(2));
                Attack(attackVec(3));
                Attack(attackVec(4));
                
                testData = db.getTestData(a(i).name);
                
                
                if ~isempty(testData)
                    foundInDB = "True";
                    nrFound = nrFound + 1;
                else
                    foundInDB = "False";
                end
                
                txt = txt + a(i).name + ":" + newline;
                txt = txt + "  - file found in database: " + foundInDB + newline;
                
                if foundInDB == "True"
                    
                    %%%%%%%%%%%%%%%   Sprites   %%%%%%%%%%%%%%%
                    if ~strcmpi(ownPokemon.name, testData.Sprite)
                        txt = txt + "  - own sprite: " + ownPokemon.name + " [" + testData.Sprite + "]" + newline;
                    else
                        nrSpriteOk = nrSpriteOk + 1;
                    end
                    
                    if ~strcmpi(enemyPokemon.name, testData.EnemySprite)
                        txt = txt + "  - enemy sprite: " + enemyPokemon.name + " [" + testData.EnemySprite + "]" + newline;
                    else
                        nrEnemySpriteOk = nrEnemySpriteOk + 1;
                    end
                    
                    %%%%%%%%%%%%%%%    Level    %%%%%%%%%%%%%%%
                    if isempty(testData.Lvl)
                        txt = txt + "  - own level: test data missing" + newline;
                    elseif int32(lvl(2)) ~= int32(testData.Lvl)
                        txt = txt + "  - own level: " + lvl(2) + " [" + testData.Lvl + "]" + newline;
                    else
                        nrLvlOk = nrLvlOk + 1;
                    end
                    
                    if isempty(testData.EnemyLvl)
                        txt = txt + "  - enemy level: test data missing" + newline;
                    elseif int32(lvl(1)) ~= int32(testData.EnemyLvl)
                        txt = txt + "  - enemy level: " + lvl(1) + " [" + testData.EnemyLvl + "]" + newline;
                    else
                        nrEnemyLvlOk = nrEnemyLvlOk + 1;
                    end
                    
                    %%%%%%%%%%%%%%%    Health    %%%%%%%%%%%%%%%
                    if isempty(testData.HP)
                        txt = txt + "  - own health: test data missing" + newline;
                    elseif health_user <= (double(testData.HP) - 0.05) || health_user >= (double(testData.HP) + 0.05)
                        txt = txt + "  - own health: " + round(health_user * 100) + "% [" + num2str(round(testData.HP) * 100) + "%]" + newline;
                    else
                        nrHpOk = nrHpOk + 1;
                    end
                    
                    if isempty(testData.EnemyHP)
                        txt = txt + "  - own level: test data missing" + newline;
                    elseif health_enemy <= (double(testData.EnemyHP) - 0.05) || health_enemy >= (double(testData.EnemyHP) + 0.05)
                        txt = txt + "  - enemy health: " + round(health_enemy * 100) + "% [" + num2str(round(testData.EnemyHP * 100)) + "%]" + newline;
                    else
                        nrEnemyHpOk = nrEnemyHpOk + 1;
                    end
                    
                    %%%%%%%%%%%%%%%    State     %%%%%%%%%%%%%%%
                    if ~strcmpi(statusOutput(1), testData.State)
                        statusOutput(1)
                        testData.State
                        txt = txt + "  - own status: " + statusOutput(1) + " [" + testData.State + "]" + newline;
                    else
                        nrStatOk = nrStatOk + 1;
                    end
                    
                    if ~strcmpi(statusOutput(2), testData.EnemyState)
                        statusOutput(2)
                        testData.EnemyState
                        txt = txt + "  - enemy status: " + statusOutput(2) + " [" + testData.EnemyState + "]" + newline;
                    else
                        nrEnemyStatOk = nrEnemyStatOk + 1;
                    end
                    
                    %%%%%%%%%%%%%%%    Attacks     %%%%%%%%%%%%%%%
                    if ~strcmpi(attackVec(1), testData.Attack1)
                        txt = txt + "  - attack1: " + attackVec(1) + " [" + testData.Attack1 + "]" + newline;
                    else
                        nrAtk1Ok = nrAtk1Ok + 1;
                    end
                    
                    if ~strcmpi(attackVec(2), testData.Attack2)
                        txt = txt + "  - attack2: " + attackVec(2) + " [" + testData.Attack2 + "]" + newline;
                    else
                        nrAtk2Ok = nrAtk2Ok + 1;
                    end
                    
                    if ~strcmpi(attackVec(3), testData.Attack3)
                        txt = txt + "  - attack3: " + attackVec(3) + " [" + testData.Attack3 + "]" + newline;
                    else
                        nrAtk3Ok = nrAtk3Ok + 1;
                    end
                    
                    if ~strcmpi(attackVec(4), testData.Attack4)
                        txt = txt + "  - attack1: " + attackVec(4) + " [" + testData.Attack4 + "]" + newline;
                    else
                        nrAtk4Ok = nrAtk4Ok + 1;
                    end
                end % found in DB
        
                txt = txt + "  - time needed: " + totalTime + newline + newline;
                app.TestingResultsTextArea.Value = "Progress: " + num2str(double(i) / sz * 100.0) + "%";
                drawnow;
            end
            
            txt = "Details:" + newline + newline + txt;
            if nrFound > 0
                totalAttacksOk = nrAtk4Ok + nrAtk3Ok + nrAtk2Ok + nrAtk1Ok;
                txt = "  - Attacks right: " + totalAttacksOk + " out of " + (4 * nrFound) + ", " + num2str(round(double(totalAttacksOk) / (4.0 * nrFound) * 100)) + "%" + newline + newline + txt;
                txt = "  - Enemy level right: " + nrEnemyLvlOk + " out of " + nrFound + ", " + num2str(round((double(nrEnemyLvlOk) / nrFound) * 100)) + "%" + newline + txt;
                txt = "  - Own Level right: " + nrLvlOk + " out of " + nrFound + ", " + num2str(round((double(nrLvlOk) / nrFound) * 100)) + "%" + newline + txt;
                txt = "  - Enemy Status right: " + nrEnemyStatOk + " out of " + nrFound + ", " + num2str(round(double(nrEnemyStatOk) / nrFound * 100)) + "%" + newline + txt;
                txt = "  - Own Status right: " + nrStatOk + " out of " + nrFound + ", " + num2str(round(double(nrEnemyStatOk) / nrFound * 100)) + "%" + newline + txt;
                txt = "  - Enemy HP right: " + nrEnemyHpOk + " out of " + nrFound + ", " + num2str(round(double(nrEnemyHpOk) / nrFound * 100)) + "%" + newline + txt;
                txt = "  - Own HP right: " + nrHpOk + " out of " + nrFound + ", " + num2str(round(double(nrHpOk) / nrFound * 100)) + "%" + newline + txt;
                txt = "  - Enemy Sprite right: " + nrEnemySpriteOk + " out of " + nrFound + ", " + num2str(round(double(nrEnemySpriteOk) / nrFound * 100)) + "%" + newline + txt;
                txt = "  - Own Sprite right: " + nrSpriteOk + " out of " + nrFound + ", " + num2str(round(double(nrSpriteOk) / nrFound * 100)) + "%" + newline + txt; 
            end
            if sz > 0
                txt = "  - Found in DB: " + nrFound + " out of " + sz + ", " + num2str(round(double(nrFound) / sz * 100)) + "%" + newline + txt;
            end
            txt = "Stats:" + newline + newline + txt; 
            
            app.TestingResultsTextArea.Value = txt;
            drawnow;
            
            app.RunFullTestButton.Enable = true;
            app.ChangeFolderPathButton.Enable = true;
        end

        % Button pushed function: ChangeFolderPathButton
        function ChangeFolderPathButtonPushed(app, event)
            path = uigetdir(app.datasetLabel.Text, 'Choose Test Data Folder');
            
            if isnumeric(path)
                if path == 0
                    return;
                end
                return;
            end
            
            if ~isempty(path)
                app.datasetLabel.Text = path;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PokmonAnalyzerUIFigure = uifigure('Visible', 'off');
            app.PokmonAnalyzerUIFigure.Name = 'Pokémon Analyzer';
            app.PokmonAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @PokmonAnalyzerUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PokmonAnalyzerUIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.GridLayout2 = uigridlayout(app.SummaryTab);
            app.Label2_1 = uilabel(app.GridLayout2);
            app.Label2_1.Text = '';
            app.Label2_3 = uilabel(app.GridLayout2);
            app.Label2_3.Text = '';
            app.Label2_6 = uilabel(app.GridLayout2);
            app.Label2_6.Text = 'Enemy Sprite:';
            app.OwnSpriteLabel = uilabel(app.GridLayout2);
            app.OwnSpriteLabel.Text = 'Own Sprite:';
            app.AttacksLabel = uilabel(app.GridLayout2);
            app.AttacksLabel.Text = 'Attacks:';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TextArea_13 = uitextarea(app.GridLayout3);
            app.TextArea_14 = uitextarea(app.GridLayout3);
            app.TextArea_15 = uitextarea(app.GridLayout3);
            app.TextArea_16 = uitextarea(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.TextArea_9 = uitextarea(app.GridLayout4);
            app.TextArea_10 = uitextarea(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.TextArea_11 = uitextarea(app.GridLayout5);
            app.TextArea_12 = uitextarea(app.GridLayout5);
            app.AttacksTab = uitab(app.TabGroup);
            app.AttacksTab.Title = 'Attacks';
            app.GridLayout = uigridlayout(app.AttacksTab);
            app.Label1 = uilabel(app.GridLayout);
            app.Label1.Text = {''; ''};
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = '';
            app.Label5 = uilabel(app.GridLayout);
            app.Label5.Text = '';
            app.Label6 = uilabel(app.GridLayout);
            app.Label6.Text = '';
            app.TextArea_1 = uitextarea(app.GridLayout);
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_3 = uitextarea(app.GridLayout);
            app.TextArea_4 = uitextarea(app.GridLayout);
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_6 = uitextarea(app.GridLayout);
            app.TextArea_7 = uitextarea(app.GridLayout);
            app.TextArea_8 = uitextarea(app.GridLayout);
            app.PokmonsTab = uitab(app.TabGroup);
            app.PokmonsTab.Title = 'Pokémons';
            app.GridLayout6 = uigridlayout(app.PokmonsTab);
            app.Label_11 = uilabel(app.GridLayout6);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.GridLayout6);
            app.Label_12.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Label_15 = uilabel(app.GridLayout7);
            app.Label_15.Text = '';
            app.Label_16 = uilabel(app.GridLayout7);
            app.Label_16.Text = '';
            app.Label_18 = uilabel(app.GridLayout7);
            app.Label_18.Text = '';
            app.Label_17 = uilabel(app.GridLayout7);
            app.Label_17.Text = '';
            app.Label_27 = uilabel(app.GridLayout7);
            app.Label_27.Text = '';
            app.Label_28 = uilabel(app.GridLayout7);
            app.Label_28.Text = '';
            app.TextArea_17 = uitextarea(app.GridLayout7);
            app.TextArea_18 = uitextarea(app.GridLayout7);
            app.TextArea_19 = uitextarea(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.Label_21 = uilabel(app.GridLayout8);
            app.Label_21.Text = '';
            app.Label_23 = uilabel(app.GridLayout8);
            app.Label_23.Text = '';
            app.Label_22 = uilabel(app.GridLayout8);
            app.Label_22.Text = '';
            app.Label_24 = uilabel(app.GridLayout8);
            app.Label_24.Text = '';
            app.Label_29 = uilabel(app.GridLayout8);
            app.Label_29.Text = '';
            app.Label_30 = uilabel(app.GridLayout8);
            app.Label_30.Text = '';
            app.TextArea_20 = uitextarea(app.GridLayout8);
            app.TextArea_21 = uitextarea(app.GridLayout8);
            app.TextArea_22 = uitextarea(app.GridLayout8);
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.TestDataFolderLabel = uilabel(app.TestingTab);
            app.TestDataFolderLabel.Text = 'Test Data Folder:';
            app.ChangeFolderPathButton = uibutton(app.TestingTab, 'push');
            app.ChangeFolderPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolderPathButtonPushed, true);
            app.ChangeFolderPathButton.Text = 'Change Folder Path';
            app.RunFullTestButton = uibutton(app.TestingTab, 'push');
            app.RunFullTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunFullTestButtonPushed, true);
            app.RunFullTestButton.Text = 'Run Full Test';
            app.TestingResultsLabel = uilabel(app.TestingTab);
            app.TestingResultsLabel.Text = {'Testing'; 'Results'};
            app.TestingResultsTextArea = uitextarea(app.TestingTab);
            app.datasetLabel = uilabel(app.TestingTab);
            app.datasetLabel.Text = '..\dataset\';
            app.Image2 = uiimage(app.TestingTab);
            app.ResultsLabel = uilabel(app.PokmonAnalyzerUIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.Image = uiimage(app.PokmonAnalyzerUIFigure);
            app.ChooseBattleScreenButton = uibutton(app.PokmonAnalyzerUIFigure, 'push');
            app.ChooseBattleScreenButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseBattleScreenButtonPushed, true);
            app.ChooseBattleScreenButton.Text = 'Choose Battle Screen';
            app.AnalyzeBattleScreenButton = uibutton(app.PokmonAnalyzerUIFigure, 'push');
            app.AnalyzeBattleScreenButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeBattleScreenButtonPushed, true);
            app.AnalyzeBattleScreenButton.Text = {'Analyze Battle Screen'; ''};
            app.BattlefieldLabel = uilabel(app.PokmonAnalyzerUIFigure);
            app.BattlefieldLabel.Text = 'Battlefield:';
            app.GenerateRandomButton = uibutton(app.PokmonAnalyzerUIFigure, 'push');
            app.GenerateRandomButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomButtonPushed, true);
            app.GenerateRandomButton.Text = {'Generate Random'; ''};
            % Show the figure after all components are created
            app.PokmonAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PokmonAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PokmonAnalyzerUIFigure)
        end
    end
end